classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        fondo      matlab.ui.control.Label
        m1         matlab.ui.control.Button
        m2         matlab.ui.control.Button
        cuadra_1   matlab.ui.control.Label
        cuadra_2   matlab.ui.control.Label
        cuadra_3   matlab.ui.control.Label
        cuadra_4   matlab.ui.control.Label
        cuadra_5   matlab.ui.control.Label
        cuadra_6   matlab.ui.control.Label
        cuadra_7   matlab.ui.control.Label
        cuadra_8   matlab.ui.control.Label
        cuadra_9   matlab.ui.control.Label
        cuadra_10  matlab.ui.control.Label
        cuadra_11  matlab.ui.control.Label
        cuadra_12  matlab.ui.control.Label
        L_11       matlab.ui.control.Lamp
        L_12       matlab.ui.control.Lamp
        L_13       matlab.ui.control.Lamp
        L_21       matlab.ui.control.Lamp
        L_22       matlab.ui.control.Lamp
        L_23       matlab.ui.control.Lamp
        L_31       matlab.ui.control.Lamp
        L_32       matlab.ui.control.Lamp
        L_33       matlab.ui.control.Lamp
        L_41       matlab.ui.control.Lamp
        L_42       matlab.ui.control.Lamp
        L_43       matlab.ui.control.Lamp
        numeros    matlab.ui.control.Label
        EditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        s % Variable comunicación serial
        t % Timer
    end
    
    methods (Access = private)
        
        function s = createSerial(app)
            delete(instrfind({'port'},{'COM4'}));
            s = serialport("COM4",19200,"Timeout",3);  
            configureTerminator(s, "CR/LF", "CR");
        end
        
        function my_callback_fcn(app,src,event)
            global modo pos_x pos_y 
            
            writeline(app.s,modo);
            textoRecibido = readline(app.s);
            if(~isempty(textoRecibido))
                if(num2str(textoRecibido) == "lata") 
                   switch modo
                        case "1"
                            writeline(app.s,"pos x");
                            pos_x = num2str(readline(app.s));
                            
                            writeline(app.s,"pos y");
                            pos_y = num2str(readline(app.s));
                            
                            pos = [pos_x  pos_y];
                           
                        case "2"
                            writeline(app.s,num2str(pos_x));
                            writeline(app.s,num2str(pos_y));
                            textoRecibido = readline(app.s);
                            if(num2str(textoRecibido)=="2")
                                writeline(app.s,"pos x");
                                pos_x = num2str(readline(app.s));
                                
                                writeline(app.s,"pos y");
                                pos_y = num2str(readline(app.s));
                                
                                pos = [pos_x  pos_y];
                            end
                            
                   end
                   
                   switch pos 
                        case "11"
                            app.L_11.Color = [1.00 0.00 0.00];
                        case "12"
                            app.L_12.Color = [1.00 0.00 0.00];
                        case "13"
                            app.L_13.Color = [1.00 0.00 0.00];
                        case "21"
                            app.L_21.Color = [1.00 0.00 0.00];
                        case "22"
                            app.L_22.Color = [1.00 0.00 0.00];
                        case "23"
                            app.L_23.Color = [1.00 0.00 0.00];
                        case "31"
                            app.L_31.Color = [1.00 0.00 0.00];
                        case "32"
                            app.L_32.Color = [1.00 0.00 0.00];
                        case "33"
                            app.L_33.Color = [1.00 0.00 0.00];
                        case "41"
                            app.L_41.Color = [1.00 0.00 0.00];
                        case "42"
                            app.L_42.Color = [1.00 0.00 0.00];
                        case "43"
                            app.L_43.Color = [1.00 0.00 0.00];
                    end
                end
            end
        end
        
        function t = createTimer(app)
            t = timer;
            t.StartDelay = 1;
            t.ExecutionMode = 'fixedSpacing';
            t.TimerFcn = @app.my_callback_fcn;
            t.Period = 1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = createSerial(app)

            app.t = createTimer(app);
            start(app.t);
            
            global modo pos_x pos_y
            modo  = "2";
            pos_x = 1;
            pos_y = 1;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.t);
            delete(app.t);
            delete(app);
        end

        % Button pushed function: m1
        function m1ButtonPushed(app, event)
            global modo
            modo = "1";
            app.EditField.Enable = 'off';
        end

        % Button pushed function: m2
        function m2ButtonPushed(app, event)
            global modo
            modo = "2";
            app.EditField.Enable = 'on';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global pos_x pos_y
            value = app.EditField.Value;
            pos_x = value(1);
            pos_y = value(3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.fondo = uilabel(app.UIFigure);
            app.fondo.Text = '';
            app.m1 = uibutton(app.UIFigure, 'push');
            app.m1.ButtonPushedFcn = createCallbackFcn(app, @m1ButtonPushed, true);
            app.m1.Text = 'MODO 1';
            app.m2 = uibutton(app.UIFigure, 'push');
            app.m2.ButtonPushedFcn = createCallbackFcn(app, @m2ButtonPushed, true);
            app.m2.Text = {'MODO 2'; ''};
            app.cuadra_1 = uilabel(app.UIFigure);
            app.cuadra_1.Text = '';
            app.cuadra_2 = uilabel(app.UIFigure);
            app.cuadra_2.Text = '';
            app.cuadra_3 = uilabel(app.UIFigure);
            app.cuadra_3.Text = '';
            app.cuadra_4 = uilabel(app.UIFigure);
            app.cuadra_4.Text = '';
            app.cuadra_5 = uilabel(app.UIFigure);
            app.cuadra_5.Text = '';
            app.cuadra_6 = uilabel(app.UIFigure);
            app.cuadra_6.Text = '';
            app.cuadra_7 = uilabel(app.UIFigure);
            app.cuadra_7.Text = '';
            app.cuadra_8 = uilabel(app.UIFigure);
            app.cuadra_8.Text = '';
            app.cuadra_9 = uilabel(app.UIFigure);
            app.cuadra_9.Text = '';
            app.cuadra_10 = uilabel(app.UIFigure);
            app.cuadra_10.Text = '';
            app.cuadra_11 = uilabel(app.UIFigure);
            app.cuadra_11.Text = '';
            app.cuadra_12 = uilabel(app.UIFigure);
            app.cuadra_12.Text = '';
            app.L_11 = uilamp(app.UIFigure);
            app.L_12 = uilamp(app.UIFigure);
            app.L_13 = uilamp(app.UIFigure);
            app.L_21 = uilamp(app.UIFigure);
            app.L_22 = uilamp(app.UIFigure);
            app.L_23 = uilamp(app.UIFigure);
            app.L_31 = uilamp(app.UIFigure);
            app.L_32 = uilamp(app.UIFigure);
            app.L_33 = uilamp(app.UIFigure);
            app.L_41 = uilamp(app.UIFigure);
            app.L_42 = uilamp(app.UIFigure);
            app.L_43 = uilamp(app.UIFigure);
            app.numeros = uilabel(app.UIFigure);
            app.numeros.Text = {'TECLEE POSICIÓN'; ''};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end